classdef FinalProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Botton_Compare       matlab.ui.control.Button
        DropDown             matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        Button_RecordFinish  matlab.ui.control.Button
        Button_Exit          matlab.ui.control.Button
        Button_Upload        matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        Button_Delete        matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        Label                matlab.ui.control.Label
        Button_Analysis      matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        Button_Play          matlab.ui.control.Button
        Button_Record        matlab.ui.control.Button
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function updatelist(app)  
            docFileInfo = dir(fullfile('*.wav'));         
            app.ListBox.Items = {docFileInfo.name};
        end
    end
    
    methods (Access = public)
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global MaterialName;
            %建立新的素材文件夾
            time = datestr(now,'mmddTHHMMSS');
            filename = ['音訊素材_',time];
            MaterialName = filename;
            mkdir(filename);
            %切換工作目錄至剛剛創建的目錄
            cd(filename);
            updatelist(app);
        end

        % Button pushed function: Button_Exit
        function Button_ExitPushed(app, event)
            ex = questdlg("你確定要親手終結這個應用程式？",'提示視窗','是','否','否');
            if ex=='是'
                   delete(app)
            end
        end

        % Button pushed function: Button_Record
        function Button_RecordPushed(app, event)
            fs = 48000;  % 采样频率
            % 创建一个录音文件：fs =48000Hz, 16-bit, 单通道
            global recorderObj;
            global recordstate;
            recordstate = 0;
            recorderObj = audiorecorder(fs, 16, 2);   
            record(recorderObj);
            app.Button_Record.FontColor = [0.65,0.65,0.65];
            app.Button_RecordFinish.FontColor = [0,0,0];
            recordstate = 1;
        end

        % Button pushed function: Button_RecordFinish
        function Button_RecordFinishPushed(app, event)
            global recorderObj;
            global recordstate;
            if recordstate==1
                fs = 48000;  % 采样频率
                stop(recorderObj);
                y = getaudiodata(recorderObj);
                ymax = max(abs(y)); % 归一化
                y = y/ymax;
                %檔案處理
                time = datestr(now,'mmddTHHMMSS');
                filename = [time,'.wav'];
                audiowrite(filename, y, fs); % 存储录音文件，在當下工作目錄，可用pwd查看
                updatelist(app);
                clear recorderObj;
                app.Button_Record.FontColor = [0,0,0];
                app.Button_RecordFinish.FontColor = [0.65,0.65,0.65];
                recordstate=0;
            end

   
        end

        % Button pushed function: Button_Upload
        function Button_UploadPushed(app, event)
            des = pwd;
            [filename,path] = uigetfile('*.wav','Select a File');
            copyfile([path,filename],des);
            updatelist(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            global value
            value = app.ListBox.Value;
            
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(app, event)
            global value
            [y, fs]=audioread(value);
            p=audioplayer(y, fs);
            playblocking(p);	% 播放音訊
            
        end

        % Button pushed function: Button_Delete
        function Button_DeletePushed(app, event)
            global value
            delete(value);
            value = app.ListBox.Value;
            updatelist(app);
        end

        % Button pushed function: Button_Analysis
        function Button_AnalysisPushed(app, event)
            global value
            [hs, ls, avg, arf, hrc, lrf, lrc, hi, li] = analyze(value);
            X = categorical({'最高音','最低音','平均音','平均高音區','平均低音區'});
            X = reordercats(X,{'最高音','最低音','平均音','平均高音區','平均低音區'});
            Y = [hs 0; ls 0; avg 0; arf hrc-arf; lrf lrc-lrf];
            b = bar(app.UIAxes,X,Y,'stacked');
           
            t = find_nearest(value);
            t2 = mergevars(t,[7,8]);
            t3 = mergevars(t2,[5,6]);
            app.UITable.Data = t3 ;
            for i = 1 : 10
                new = table2array(t3(i,1));
                app.DropDown.Items = [app.DropDown.Items new];
            end
            app.DropDown.ItemsData = {t(1,2:8),t(2,2:8),t(3,2:8),t(4,2:8),t(5,2:8),t(6,2:8),t(7,2:8),t(8,2:8),t(9,2:8),t(10,2:8)};
            
        end

        % Button pushed function: Botton_Compare
        function Botton_CompareButtonPushed(app, event)
         global value;
         a = table2array(app.DropDown.Value);
         X = categorical({'最高音','最低音','平均音','平均高音區','平均低音區'});
         X = reordercats(X,{'最高音','最低音','平均音','平均高音區','平均低音區'});
         Y1 = [str2double(a(1)) 0; str2double(a(2)) 0; str2double(a(3)) 0; str2double(a(4)) str2double(a(5))-str2double(a(4));str2double(a(6)) str2double(a(7))-str2double(a(6))];
         c=bar(app.UIAxes_2,X,Y1,'stacked');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '錄音分析')
            app.UIAxes.YLim = [0 1300];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '歌曲分析')
            app.UIAxes_2.YLim = [0 1300];
            app.Button_Record = uibutton(app.UIFigure, 'push');
            app.Button_Record.ButtonPushedFcn = createCallbackFcn(app, @Button_RecordPushed, true);
            app.Button_Record.Text = '錄製';
            app.Button_Play = uibutton(app.UIFigure, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @Button_PlayPushed, true);
            app.Button_Play.Text = '播放';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'歌曲名稱'; '最高音'; '最低音'; '平均音'; '平均高音區'; '平均低音區'};
            app.UITable.RowName = {};
            app.Button_Analysis = uibutton(app.UIFigure, 'push');
            app.Button_Analysis.ButtonPushedFcn = createCallbackFcn(app, @Button_AnalysisPushed, true);
            app.Button_Analysis.Text = '開始分析';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '錄好歌曲列表';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Button_Delete = uibutton(app.UIFigure, 'push');
            app.Button_Delete.ButtonPushedFcn = createCallbackFcn(app, @Button_DeletePushed, true);
            app.Button_Delete.Text = '刪除';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '歌曲近似指標介面';
            app.Button_Upload = uibutton(app.UIFigure, 'push');
            app.Button_Upload.ButtonPushedFcn = createCallbackFcn(app, @Button_UploadPushed, true);
            app.Button_Upload.Text = '上傳';
            app.Button_Exit = uibutton(app.UIFigure, 'push');
            app.Button_Exit.ButtonPushedFcn = createCallbackFcn(app, @Button_ExitPushed, true);
            app.Button_Exit.Text = '結束程式';
            app.Button_RecordFinish = uibutton(app.UIFigure, 'push');
            app.Button_RecordFinish.ButtonPushedFcn = createCallbackFcn(app, @Button_RecordFinishPushed, true);
            app.Button_RecordFinish.Text = '錄製完成';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '比較歌曲';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.Botton_Compare = uibutton(app.UIFigure, 'push');
            app.Botton_Compare.ButtonPushedFcn = createCallbackFcn(app, @Botton_CompareButtonPushed, true);
            app.Botton_Compare.Text = '比較';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end